classdef basic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        Q1Tab                         matlab.ui.container.Tab
        NeedleCountSliderLabel        matlab.ui.control.Label
        NeedleCountSlider             matlab.ui.control.Slider
        WhatareweplottingButtonGroup  matlab.ui.container.ButtonGroup
        SquaresButton                 matlab.ui.control.RadioButton
        NeedlesButton                 matlab.ui.control.RadioButton
        BestagonsButton               matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
        Q2Tab                         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        N	=	1000				% Number of needles
		L	=	0.3					% Length of needles
    end
    
    methods (Access = private)
        
		function updateNeedleCount(app, needleCount)
			app.N = needleCount;
			updatePlot(app);
		end
		
        function updatePlot(app)
			size = 5;
			disp(app.N);
			x_coord = app.L + rand(1,app.N) * (size - 2*app.L);
			y_coord = app.L + rand(1,app.N) * (size - 2*app.L);

			angles = rand(1, app.N) * 360;
			x_angle = x_coord + app.L * cosd(angles);
			y_angle = y_coord + app.L * sind(angles);
			
			plot(app.UIAxes, [x_coord; x_angle], [y_coord; y_angle], 'LineWidth', 2);
			
			hold on
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NeedleCountSlider
        function NeedleCountSliderValueChanged(app, event)
            value = ceil(app.NeedleCountSlider.Value);
            updateNeedleCount(app, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Q1Tab = uitab(app.TabGroup);
            app.Q1Tab.Title = 'Q1';
            app.NeedleCountSliderLabel = uilabel(app.Q1Tab);
            app.NeedleCountSliderLabel.Text = 'Needle Count';
            app.NeedleCountSlider = uislider(app.Q1Tab);
            app.NeedleCountSlider.Limits = [0 1000];
            app.NeedleCountSlider.ValueChangedFcn = createCallbackFcn(app, @NeedleCountSliderValueChanged, true);
            app.WhatareweplottingButtonGroup = uibuttongroup(app.Q1Tab);
            app.WhatareweplottingButtonGroup.Title = 'What are we plotting?';
            app.SquaresButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.SquaresButton.Text = 'Squares';
            app.NeedlesButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.NeedlesButton.Text = 'Needles';
            app.NeedlesButton.Value = true;
            app.BestagonsButton = uiradiobutton(app.WhatareweplottingButtonGroup);
            app.BestagonsButton.Text = 'Bestagons';
            app.UIAxes = uiaxes(app.Q1Tab);
            title(app.UIAxes, 'Floor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Q2Tab = uitab(app.TabGroup);
            app.Q2Tab.Title = 'Q2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end